classdef meteoroid_orbits < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        IntegrationTimeEditFieldLabel  matlab.ui.control.Label
        IntegrationTimeEditField       matlab.ui.control.NumericEditField
        IntegrationTimeEditFieldLabel_2  matlab.ui.control.Label
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        hhEditFieldLabel               matlab.ui.control.Label
        hhEditField                    matlab.ui.control.NumericEditField
        mmEditFieldLabel               matlab.ui.control.Label
        mmEditField                    matlab.ui.control.NumericEditField
        ssEditFieldLabel               matlab.ui.control.Label
        ssEditField                    matlab.ui.control.NumericEditField
        RadarDropDownLabel             matlab.ui.control.Label
        RadarDropDown                  matlab.ui.control.DropDown
        CoordinatesDropDownLabel       matlab.ui.control.Label
        CoordinatesDropDown            matlab.ui.control.DropDown
        FrameDropDownLabel             matlab.ui.control.Label
        FrameDropDown                  matlab.ui.control.DropDown
        InitialConditionsPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        xEditFieldLabel                matlab.ui.control.Label
        xEditField                     matlab.ui.control.NumericEditField
        yEditFieldLabel                matlab.ui.control.Label
        yEditField                     matlab.ui.control.NumericEditField
        zEditFieldLabel                matlab.ui.control.Label
        zEditField                     matlab.ui.control.NumericEditField
        dxdtEditFieldLabel             matlab.ui.control.Label
        dxdtEditField                  matlab.ui.control.NumericEditField
        dydtEditFieldLabel             matlab.ui.control.Label
        dydtEditField                  matlab.ui.control.NumericEditField
        dzdtEditFieldLabel             matlab.ui.control.Label
        dzdtEditField                  matlab.ui.control.NumericEditField
        WarningsTextAreaLabel          matlab.ui.control.Label
        WarningsTextArea               matlab.ui.control.TextArea
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        PerturbationsPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ThreeBodyLabel                 matlab.ui.control.Label
        ThreeBodySwitch                matlab.ui.control.ToggleSwitch
        DragSwitchLabel                matlab.ui.control.Label
        DragSwitch                     matlab.ui.control.ToggleSwitch
        SRPSwitchLabel                 matlab.ui.control.Label
        SRPSwitch                      matlab.ui.control.ToggleSwitch
        J2SwitchLabel                  matlab.ui.control.Label
        J2Switch                       matlab.ui.control.ToggleSwitch
        RightPanel                     matlab.ui.container.Panel
        ComputeButton                  matlab.ui.control.Button
        OrbitalElementsPanel           matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        aAUEditFieldLabel              matlab.ui.control.Label
        aAUEditField                   matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        eEditField                     matlab.ui.control.NumericEditField
        idegEditFieldLabel             matlab.ui.control.Label
        idegEditField                  matlab.ui.control.NumericEditField
        degEditFieldLabel              matlab.ui.control.Label
        degEditField                   matlab.ui.control.NumericEditField
        degEditField_2Label            matlab.ui.control.Label
        degEditField_2                 matlab.ui.control.NumericEditField
        AdditionalPlottingPanel        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        EarthOrbitSwitchLabel          matlab.ui.control.Label
        EarthOrbitSwitch               matlab.ui.control.Switch
        JupiterOrbitSwitchLabel        matlab.ui.control.Label
        JupiterOrbitSwitch             matlab.ui.control.Switch
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % these commands 
            addpath(genpath('src'))
            cspice_furnsh('src/kernels/loadkernels.tm') % load the SPICE kernels
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            date = app.DateDatePicker.Value;
            date.Hour = app.hhEditField.Value;
            date.Minute = app.mmEditField.Value;
            date.Second = app.ssEditField.Value;
            time = datevec(date);
            et0 = cspice_str2et(datestr(time));
            
            if app.RadarDropDown.Value == "ALTAIR"
                % geodetic location of ALTAIR
                phi_radar = 0.1640; %[rad] latitude
                lambda_radar = 2.9231; %[rad] longitude
                h_radar = 0.0835; % [km] altitude
            end

            coords = app.CoordinatesDropDown.Value;
            frame = app.FrameDropDown.Value;
            switch coords
                case "Cartesian" % we expect the frame to be ENU, ECEF, or ECI
                    x = app.xEditField.Value; % Get
                    y = app.yEditField.Value; % Get
                    z = app.zEditField.Value; % Get
                    xdot = app.dxdtEditField.Value; % Get
                    ydot = app.dydtEditField.Value; % Get
                    zdot = app.dzdtEditField.Value; % Get
                    state = [x;y;z;xdot;ydot;zdot];
                    switch frame
                        case "ENU"
                            x_ENU = state;
                            [x_ECEF, R1] = ENU2ECEF(x_ENU, phi_radar, lambda_radar, h_radar);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*R1;
                        case "ECEF"
                            x_ECEF = state;
                            [x_ECI, R] = ECEF2ECI(x_ECEF, et0);
                        case "ECI"
                            x_ECI = state;
                            R = eye(6);
                    end
                case "Spherical" % we expect the frame to be purely ENU
                    r = app.xEditField.Value; % Get
                    alpha = app.yEditField.Value; % Get
                    beta = app.zEditField.Value; % Get
                    rdot = app.dxdtEditField.Value; % Get
                    alphadot = app.dydtEditField.Value; % Get
                    betadot = app.dzdtEditField.Value; % Get
                    state = [r;alpha;beta;rdot;alphadot;betadot];
                    switch frame
                        case "ENU"
                            x_sph = state;
                            [x_ENU, J1] = sph2ENU(x_sph);
                            [x_ECEF, R1] = ENU2ECEF(x_ENU, phi_radar, lambda_radar, h_radar);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*R1*J1;
                        case "ECEF"
                            error("unexpected frame for chosen coordinates")
                        case "ECI"
                            error("unexpected frame for chosen coordinates")
                    end
                case "Lat/Lon/Az/El" % we expect the frame to be purely ECEF
                    phi = app.xEditField.Value; % Get
                    lambda = app.yEditField.Value; % Get
                    h = app.zEditField.Value; % Get
                    alpha = app.dxdtEditField.Value; % Get
                    beta = app.dydtEditField.Value; % Get
                    v = app.dzdtEditField.Value; % Get
                    state = [phi;lambda;h;alpha;beta;v];
                    switch frame
                        case "ENU"
                            error("unexpected frame for chosen coordinates")
                        case "ECEF"
                            x_latlonazel = state;
                            [x_ECEF, J2] = latlonazel2ECEF(x_latlonazel);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*J2;
                        case "ECI"
                            error("unexpected frame for chosen coordinates")
                    end
            end
                       
            m = 1e-9; % [kg]
            A = 4.1347e-9; % [m^2]
            
            flag_TB = (app.ThreeBodySwitch.Value == "On");            
            flag_drag = (app.DragSwitch.Value == "On");
            flag_SRP = (app.SRPSwitch.Value == "On");
            flags = [flag_drag; flag_SRP; flag_TB];
            bodies = {'EARTH','MOON','JUPITER_BARYCENTER'};
            tf = -app.IntegrationTimeEditField.Value*365*86400;
            evfcn_e = @(t,x) ef_rval3d(t,x,1e6); % past Earth's SOI
            evfcn_j = @(t,x) ef_rval3d(t,x,1e9); % past Jupiter's Orbit
            tol = 1e-12;
            trace = 0;
            
            vec0_ECI = [x_ECI];%; reshape(eye(6),36,1)];
            [tt_ECI, xx_ECI] = ode78e(@(t,vec) dynamics_ECI(t, vec, et0, flags, A, m, {'MOON','SUN'}), 0, tf, vec0_ECI, tol, trace, evfcn_e);
            x_ECI_f = xx_ECI(end,1:6)';
            t_int_ECI = tt_ECI(end);
            [x_SCI, R4] = ECI2SCI(x_ECI_f, et0 + t_int_ECI);
            vec0_SCI = [x_SCI];%; reshape(eye(6),36,1)];
            [tt_SCI, xx_SCI] = ode78e(@(t,x) dynamics_SCI(t, x, et0 + t_int_ECI, flags, A, m, {'MOON','EARTH','JUPITER_BARYCENTER'}), 0, tf, vec0_SCI, tol, trace, evfcn_j);
            x_SCI_f = xx_SCI(end,1:6)';        
            t_int_SCI = tt_SCI(end);

            GM = cspice_bodvrd( 'SUN', 'GM', 1 );
            AU = cspice_convrt(1, 'AU', 'km'); %[km]
            
            [a,e,i,Omega,omega] = cart2oe(x_SCI_f, GM);
            a = a/AU;
            
            app.aAUEditField.Value = a;
            app.eEditField.Value = e;
            app.idegEditField.Value = i;
            app.degEditField.Value = Omega;
            app.degEditField_2.Value = omega;
            
            app.UIAxes.cla
            plot(app.UIAxes, xx_SCI(:,1)/AU, xx_SCI(:,2)/AU)
            hold(app.UIAxes, 'on')
            app.UIAxes.XLabel.String = 'X [AU]';
            app.UIAxes.YLabel.String = 'Y [AU]';
            if app.EarthOrbitSwitch.Value == "On"
                theta = linspace(0,2*pi, 100); 
                plot(app.UIAxes,cos(theta),sin(theta),'b:','LineWidth',2);
            end
            if app.JupiterOrbitSwitch.Value == "On"
                theta = linspace(0,2*pi, 100); 
                plot(app.UIAxes,5.2*cos(theta),5.2*sin(theta),'r:','LineWidth',2);
            end
            app.UIAxes.Title.String = 'Meteoroid Orbit';
        end

        % Value changed function: CoordinatesDropDown
        function CoordinatesDropDownValueChanged(app, event)
            value = app.CoordinatesDropDown.Value;
            switch value
                case "Cartesian"
                    app.xEditFieldLabel.Text = "x";
                    app.yEditFieldLabel.Text = "y";
                    app.zEditFieldLabel.Text = "z";
                    app.dxdtEditFieldLabel.Text = "dx/dt";
                    app.dydtEditFieldLabel.Text = "dy/dt";
                    app.dzdtEditFieldLabel.Text = "dz/dt";
                case "Spherical"
                    app.xEditFieldLabel.Text = "r";
                    app.yEditFieldLabel.Text = "α (az)";
                    app.zEditFieldLabel.Text = "β (el)";
                    app.dxdtEditFieldLabel.Text = "dr/dt";
                    app.dydtEditFieldLabel.Text = "dα/dt";
                    app.dzdtEditFieldLabel.Text = "dβ/dt";
                case "Lat/Lon/Az/El"
                    app.xEditFieldLabel.Text = "φ (lat)";
                    app.yEditFieldLabel.Text = "λ (lon)";
                    app.zEditFieldLabel.Text = "h (alt)";
                    app.dxdtEditFieldLabel.Text = "α (az)";
                    app.dydtEditFieldLabel.Text = "β (el)";
                    app.dzdtEditFieldLabel.Text = "v";
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {503, 503, 503};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {503, 503};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {269, '1x', 209};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.IntegrationTimeEditFieldLabel = uilabel(app.LeftPanel);
            app.IntegrationTimeEditFieldLabel.Text = 'Integration Time';
            app.IntegrationTimeEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntegrationTimeEditField.Value = 10;
            app.IntegrationTimeEditFieldLabel_2 = uilabel(app.LeftPanel);
            app.IntegrationTimeEditFieldLabel_2.Text = 'yrs';
            app.DateDatePickerLabel = uilabel(app.LeftPanel);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.LeftPanel);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DateDatePicker.Value = datetime([2008 1 2]);
            app.hhEditFieldLabel = uilabel(app.LeftPanel);
            app.hhEditFieldLabel.Text = 'hh';
            app.hhEditField = uieditfield(app.LeftPanel, 'numeric');
            app.hhEditField.RoundFractionalValues = 'on';
            app.hhEditField.Value = 18;
            app.mmEditFieldLabel = uilabel(app.LeftPanel);
            app.mmEditFieldLabel.Text = 'mm';
            app.mmEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mmEditField.RoundFractionalValues = 'on';
            app.mmEditField.Value = 10;
            app.ssEditFieldLabel = uilabel(app.LeftPanel);
            app.ssEditFieldLabel.Text = 'ss';
            app.ssEditField = uieditfield(app.LeftPanel, 'numeric');
            app.ssEditField.Value = 20.5249;
            app.RadarDropDownLabel = uilabel(app.LeftPanel);
            app.RadarDropDownLabel.Text = 'Radar';
            app.RadarDropDown = uidropdown(app.LeftPanel);
            app.RadarDropDown.Items = {'ALTAIR', 'N/A', 'N/A', 'N/A'};
            app.RadarDropDown.Value = 'ALTAIR';
            app.CoordinatesDropDownLabel = uilabel(app.LeftPanel);
            app.CoordinatesDropDownLabel.Text = 'Coordinates';
            app.CoordinatesDropDown = uidropdown(app.LeftPanel);
            app.CoordinatesDropDown.Items = {'Cartesian', 'Spherical', 'Lat/Lon/Az/El'};
            app.CoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinatesDropDownValueChanged, true);
            app.CoordinatesDropDown.Value = 'Cartesian';
            app.FrameDropDownLabel = uilabel(app.LeftPanel);
            app.FrameDropDownLabel.Text = 'Frame';
            app.FrameDropDown = uidropdown(app.LeftPanel);
            app.FrameDropDown.Items = {'ENU', 'ECEF', 'ECI'};
            app.FrameDropDown.Value = 'ENU';
            app.InitialConditionsPanel = uipanel(app.LeftPanel);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.GridLayout2 = uigridlayout(app.InitialConditionsPanel);
            app.xEditFieldLabel = uilabel(app.GridLayout2);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout2, 'numeric');
            app.xEditField.Value = 30.1051;
            app.yEditFieldLabel = uilabel(app.GridLayout2);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.GridLayout2, 'numeric');
            app.yEditField.Value = 7.4197;
            app.zEditFieldLabel = uilabel(app.GridLayout2);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.GridLayout2, 'numeric');
            app.zEditField.Value = 109.086;
            app.dxdtEditFieldLabel = uilabel(app.GridLayout2);
            app.dxdtEditFieldLabel.Text = 'dx/dt';
            app.dxdtEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dxdtEditField.Value = -12.6807;
            app.dydtEditFieldLabel = uilabel(app.GridLayout2);
            app.dydtEditFieldLabel.Text = 'dy/dt';
            app.dydtEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dydtEditField.Value = -5.4241;
            app.dzdtEditFieldLabel = uilabel(app.GridLayout2);
            app.dzdtEditFieldLabel.Text = 'dz/dt';
            app.dzdtEditField = uieditfield(app.GridLayout2, 'numeric');
            app.dzdtEditField.Value = -57.5224;
            app.WarningsTextAreaLabel = uilabel(app.LeftPanel);
            app.WarningsTextAreaLabel.Text = 'Warnings';
            app.WarningsTextArea = uitextarea(app.LeftPanel);
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Meteoroid Orbit')
            xlabel(app.UIAxes, 'X [AU]')
            ylabel(app.UIAxes, 'Y [AU]')
            app.PerturbationsPanel = uipanel(app.CenterPanel);
            app.PerturbationsPanel.Title = 'Perturbations';
            app.GridLayout4 = uigridlayout(app.PerturbationsPanel);
            app.ThreeBodyLabel = uilabel(app.GridLayout4);
            app.ThreeBodyLabel.Text = {'Three-'; 'Body'};
            app.ThreeBodySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.DragSwitchLabel = uilabel(app.GridLayout4);
            app.DragSwitchLabel.Text = 'Drag';
            app.DragSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.SRPSwitchLabel = uilabel(app.GridLayout4);
            app.SRPSwitchLabel.Text = 'SRP';
            app.SRPSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.J2SwitchLabel = uilabel(app.GridLayout4);
            app.J2SwitchLabel.Text = 'J2';
            app.J2Switch = uiswitch(app.GridLayout4, 'toggle');
            app.RightPanel = uipanel(app.GridLayout);
            app.ComputeButton = uibutton(app.RightPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.OrbitalElementsPanel = uipanel(app.RightPanel);
            app.OrbitalElementsPanel.Title = 'Orbital Elements';
            app.GridLayout3 = uigridlayout(app.OrbitalElementsPanel);
            app.aAUEditFieldLabel = uilabel(app.GridLayout3);
            app.aAUEditFieldLabel.Text = 'a [AU]';
            app.aAUEditField = uieditfield(app.GridLayout3, 'numeric');
            app.aAUEditField.Editable = 'off';
            app.eEditFieldLabel = uilabel(app.GridLayout3);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eEditField.Editable = 'off';
            app.idegEditFieldLabel = uilabel(app.GridLayout3);
            app.idegEditFieldLabel.Text = 'i [deg]';
            app.idegEditField = uieditfield(app.GridLayout3, 'numeric');
            app.idegEditField.Editable = 'off';
            app.degEditFieldLabel = uilabel(app.GridLayout3);
            app.degEditFieldLabel.Text = 'Ω [deg]';
            app.degEditField = uieditfield(app.GridLayout3, 'numeric');
            app.degEditField.Editable = 'off';
            app.degEditField_2Label = uilabel(app.GridLayout3);
            app.degEditField_2Label.Text = 'ω [deg]';
            app.degEditField_2 = uieditfield(app.GridLayout3, 'numeric');
            app.degEditField_2.Editable = 'off';
            app.AdditionalPlottingPanel = uipanel(app.RightPanel);
            app.AdditionalPlottingPanel.Title = 'Additional Plotting';
            app.GridLayout5 = uigridlayout(app.AdditionalPlottingPanel);
            app.EarthOrbitSwitchLabel = uilabel(app.GridLayout5);
            app.EarthOrbitSwitchLabel.Text = 'Earth Orbit';
            app.EarthOrbitSwitch = uiswitch(app.GridLayout5, 'slider');
            app.EarthOrbitSwitch.Value = 'On';
            app.JupiterOrbitSwitchLabel = uilabel(app.GridLayout5);
            app.JupiterOrbitSwitchLabel.Text = 'Jupiter Orbit';
            app.JupiterOrbitSwitch = uiswitch(app.GridLayout5, 'slider');
            app.JupiterOrbitSwitch.Value = 'On';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = meteoroid_orbits

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end