classdef moda < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MODAUIFigure                   matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        InitialConditionsPanel         matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        in1Label                       matlab.ui.control.Label
        in1EditField                   matlab.ui.control.NumericEditField
        in2Label                       matlab.ui.control.Label
        in2EditField                   matlab.ui.control.NumericEditField
        in3Label                       matlab.ui.control.Label
        in3EditField                   matlab.ui.control.NumericEditField
        in4Label                       matlab.ui.control.Label
        in4EditField                   matlab.ui.control.NumericEditField
        in5Label                       matlab.ui.control.Label
        in5EditField                   matlab.ui.control.NumericEditField
        in6Label                       matlab.ui.control.Label
        in6EditField                   matlab.ui.control.NumericEditField
        StdDevLabel                    matlab.ui.control.Label
        ValLabel                       matlab.ui.control.Label
        CoordLabel                     matlab.ui.control.Label
        in1_stdEditField               matlab.ui.control.NumericEditField
        in2_stdEditField               matlab.ui.control.NumericEditField
        in3_stdEditField               matlab.ui.control.NumericEditField
        in4_stdEditField               matlab.ui.control.NumericEditField
        in5_stdEditField               matlab.ui.control.NumericEditField
        in6_stdEditField               matlab.ui.control.NumericEditField
        CoordinatesDropDownLabel       matlab.ui.control.Label
        CoordinatesDropDown            matlab.ui.control.DropDown
        FrameDropDownLabel             matlab.ui.control.Label
        FrameDropDown                  matlab.ui.control.DropDown
        MasskgEditFieldLabel           matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        AreamLabel                     matlab.ui.control.Label
        AEditField                     matlab.ui.control.NumericEditField
        PreprogrammedMeteoroidsLabel   matlab.ui.control.Label
        PreprogrammedMeteoroidsDropDown  matlab.ui.control.DropDown
        TimePanel                      matlab.ui.container.Panel
        GridLayout6                    matlab.ui.container.GridLayout
        ssEditFieldLabel               matlab.ui.control.Label
        ssEditField                    matlab.ui.control.NumericEditField
        mmEditFieldLabel               matlab.ui.control.Label
        mmEditField                    matlab.ui.control.NumericEditField
        DateDatePickerLabel            matlab.ui.control.Label
        DateDatePicker                 matlab.ui.control.DatePicker
        IntegrationTimeEditFieldLabel  matlab.ui.control.Label
        IntegrationTimeEditField       matlab.ui.control.NumericEditField
        hhEditFieldLabel               matlab.ui.control.Label
        hhEditField                    matlab.ui.control.NumericEditField
        timeUnitDropDown               matlab.ui.control.DropDown
        RadarPanel                     matlab.ui.container.Panel
        GridLayout7                    matlab.ui.container.GridLayout
        altEditFieldLabel              matlab.ui.control.Label
        altEditField                   matlab.ui.control.NumericEditField
        lonEditFieldLabel              matlab.ui.control.Label
        lonEditField                   matlab.ui.control.NumericEditField
        latEditFieldLabel              matlab.ui.control.Label
        latEditField                   matlab.ui.control.NumericEditField
        RadarDropDownLabel             matlab.ui.control.Label
        RadarDropDown                  matlab.ui.control.DropDown
        CenterPanel                    matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        PerturbationsPanel             matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        ThreeBodyLabel                 matlab.ui.control.Label
        ThreeBodySwitch                matlab.ui.control.ToggleSwitch
        DragSwitchLabel                matlab.ui.control.Label
        DragSwitch                     matlab.ui.control.ToggleSwitch
        SRPSwitchLabel                 matlab.ui.control.Label
        SRPSwitch                      matlab.ui.control.ToggleSwitch
        J2SwitchLabel                  matlab.ui.control.Label
        J2Switch                       matlab.ui.control.ToggleSwitch
        SwitchLabel                    matlab.ui.control.Label
        Switch                         matlab.ui.control.ToggleSwitch
        XYViewButton                   matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        ComputeButton                  matlab.ui.control.Button
        OrbitalElementsPanel           matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        aAUEditFieldLabel              matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        eEditFieldLabel                matlab.ui.control.Label
        eEditField                     matlab.ui.control.NumericEditField
        idegEditFieldLabel             matlab.ui.control.Label
        iEditField                     matlab.ui.control.NumericEditField
        degEditFieldLabel              matlab.ui.control.Label
        oEditField                     matlab.ui.control.NumericEditField
        degEditField_2Label            matlab.ui.control.Label
        uEditField                     matlab.ui.control.NumericEditField
        ValLabel_2                     matlab.ui.control.Label
        StdDevLabel_2                  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        kmsLabel                       matlab.ui.control.Label
        EEditField                     matlab.ui.control.NumericEditField
        a_stdEditField                 matlab.ui.control.NumericEditField
        e_stdEditField                 matlab.ui.control.NumericEditField
        i_stdEditField                 matlab.ui.control.NumericEditField
        o_stdEditField                 matlab.ui.control.NumericEditField
        u_stdEditField                 matlab.ui.control.NumericEditField
        E_stdEditField                 matlab.ui.control.NumericEditField
        AdditionalPlottingPanel        matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        EarthOrbitSwitchLabel          matlab.ui.control.Label
        EarthOrbitSwitch               matlab.ui.control.Switch
        JupiterOrbitSwitchLabel        matlab.ui.control.Label
        JupiterOrbitSwitch             matlab.ui.control.Switch
        NEditFieldLabel                matlab.ui.control.Label
        NEditField                     matlab.ui.control.NumericEditField
        MessagesTextAreaLabel          matlab.ui.control.Label
        MessagesTextArea               matlab.ui.control.TextArea
        MonteCarloCheckBox             matlab.ui.control.CheckBox
        NondimensionalizebeforeintegrationCheckBox  matlab.ui.control.CheckBox
        SignificantDigitsEditFieldLabel  matlab.ui.control.Label
        SignificantDigitsEditField     matlab.ui.control.NumericEditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        AU; % [km] Astronomical Unit
    end
    
    methods (Access = private)
        
        function [] = plot_results(app, et0, t_int_ECI, xx_ECI, t_int_SCI, xx_SCI)
            % et0 [sec] (scalar) observation ephemeris time
            % t_int_ECI [sec] (scalar) time integrated in ECI frame
            % xx_ECI [NON] (Nx6) nondimensional states of integration in ECI
            % frame
            % t_int_SCI [sec] (scalar) time integrated in SCI frame
            % xx_SCI [NON] (Nx6)
            
            xx_ECI = make_column_vecs(xx_ECI);
            xx_SCI = make_column_vecs(xx_SCI);
            
            earth_at_observation = cspice_spkpos('EARTH', et0, 'ECLIPJ2000', 'NONE', 'SUN')/app.AU;
            jupiter_at_observation = cspice_spkpos('JUPITER_BARYCENTER', et0, 'ECLIPJ2000', 'NONE', 'SUN')/app.AU;
            earth_at_start = cspice_spkpos('EARTH', et0:-86400*10:et0+t_int_ECI+t_int_SCI, 'ECLIPJ2000', 'NONE', 'SUN')/app.AU;
            jupiter_at_start = cspice_spkpos('JUPITER_BARYCENTER', et0:-86400*10:et0+t_int_ECI+t_int_SCI, 'ECLIPJ2000', 'NONE', 'SUN')/app.AU;
            jupiter_full_orbit = cspice_spkpos('JUPITER_BARYCENTER', et0:-86400*10:et0-12*365*86400, 'ECLIPJ2000', 'NONE', 'SUN')/app.AU;
            
            app.UIAxes.cla
            plot3(app.UIAxes, xx_SCI(1,:), xx_SCI(2,:), xx_SCI(3,:))
            hold(app.UIAxes, 'on')
            app.UIAxes.XLabel.String = 'X [AU]';
            app.UIAxes.YLabel.String = 'Y [AU]';
            if app.EarthOrbitSwitch.Value == "On"
                theta = linspace(0,2*pi, 100); 
                plot3(app.UIAxes,cos(theta),sin(theta),zeros(100,1),'b:','LineWidth',2);
                plot3(app.UIAxes,earth_at_observation(1),earth_at_observation(2),earth_at_observation(3),'bo')
                plot3(app.UIAxes,earth_at_start(1,:),earth_at_start(2,:),earth_at_start(3,:),'b-','LineWidth',4)
            end
            if app.JupiterOrbitSwitch.Value == "On"
%                 theta = linspace(0,2*pi, 100); the circle doesn't
%                 coincide with Jupiter's actual orbit very well.
%                 plot3(app.UIAxes,5.2*cos(theta),5.2*sin(theta), zeros(100,1), 'r:','LineWidth',2);
                plot3(app.UIAxes,jupiter_full_orbit(1,:),jupiter_full_orbit(2,:),jupiter_full_orbit(3,:),'r:','LineWidth',2)
                plot3(app.UIAxes,jupiter_at_observation(1),jupiter_at_observation(2),jupiter_at_observation(3),'ro')
                plot3(app.UIAxes,jupiter_at_start(1,:),jupiter_at_start(2,:),jupiter_at_start(3,:),'r-','LineWidth',4)
            end
            app.UIAxes.Title.String = 'Meteoroid Orbit';
            grid(app.UIAxes, 'On')
            legend(app.UIAxes, 'Meteoroid','Location','NorthEast')
%             legend(app.UIAxes, 'Meteoroid','Earth Orbit','Earth Path','Earth Impact','Jupiter Orbit','Jupiter Path','Jupiter at Earth Impact','Location','NorthEast')
            axis(app.UIAxes, [-6,6,-6,6,-6,6])    
        end
        
        function [phi_radar, lambda_radar, h_radar] = get_radar_coords(app)
            try
                phi_radar = app.latEditField.Value; %[deg]
                lambda_radar = app.lonEditField.Value; %[deg]
                h_radar = app.altEditField.Value/1000; %[km] the user inputs in meters, we convert into km before computing 
            catch
                app.MessagesTextArea.Value = "Error: Please select a radar or input radar coordinates";
            end
        end
        
        function tf = get_integration_time(app)
            switch app.timeUnitDropDown.Value
                case "years"
                    conversion_factor = 365*86400;
                case "days"
                    conversion_factor = 86400;
                case "hours"
                    conversion_factor = 3600;
                case "minutes"
                    conversion_factor = 60;
                case "seconds"
                    conversion_factor = 1;
            end  
            tf = -app.IntegrationTimeEditField.Value*conversion_factor; % [sec]
        end
        
        function total_integration_time = get_total_integration_time(app, t_int_SCI)
            switch app.timeUnitDropDown.Value
                case "years"
                    conversion_factor = 365*86400;
                case "days"
                    conversion_factor = 86400;
                case "hours"
                    conversion_factor = 3600;
                case "minutes"
                    conversion_factor = 60;
                case "seconds"
                    conversion_factor = 1;
            end  
            total_integration_time = "Total Integration Time: " + num2str(t_int_SCI/conversion_factor) + " " + app.timeUnitDropDown.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % these commands 
            addpath(genpath('src'))
            cspice_furnsh('src/kernels/loadkernels.tm') % load the SPICE kernels
            app.AU = cspice_convrt(1, 'AU', 'km'); %[km]  
            app.PreprogrammedMeteoroidsDropDown.Value = "Kosice";
            app.PreprogrammedMeteoroidsDropDownValueChanged;
        end

        % Callback function: ComputeButton, MODAUIFigure
        function ComputeButtonPushed(app, event)
            tic
            
            date = app.DateDatePicker.Value;
            date.Hour = app.hhEditField.Value;
            date.Minute = app.mmEditField.Value;
            date.Second = app.ssEditField.Value;
            time = datevec(date);
            et0 = cspice_str2et(datestr(time));

            coords = app.CoordinatesDropDown.Value;
            frame = app.FrameDropDown.Value;
            
            state = [app.in1EditField.Value;
                     app.in2EditField.Value;
                     app.in3EditField.Value;
                     app.in4EditField.Value;
                     app.in5EditField.Value;
                     app.in6EditField.Value];
                 
            Sigma = diag([app.in1_stdEditField.Value^2;
                          app.in2_stdEditField.Value^2;
                          app.in3_stdEditField.Value^2;
                          app.in4_stdEditField.Value^2;
                          app.in5_stdEditField.Value^2;
                          app.in6_stdEditField.Value^2]);
                      
            if app.MonteCarloCheckBox.Value
                N = app.NEditField.Value;
                particles = mvnrnd(state, Sigma, N);
            end
            
            switch coords
                case "Cartesian" % we expect the frame to be ENU, ECEF, or ECI
%                   [x,y,z,xdot,ydot,zdot]
                    switch frame
                        case "ENU"
                
                            [phi_radar, lambda_radar, h_radar] = app.get_radar_coords();
                            
                            x_ENU = state;
                            [x_ECEF, R1] = ENU2ECEF(x_ENU, phi_radar, lambda_radar, h_radar);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*R1;
                            
                            if app.MonteCarloCheckBox.Value
                                for i = 1:N
                                    particles(i,:) = ENU2ECEF(particles(i,:), phi_radar, lambda_radar, h_radar)';
                                    particles(i,:) = ECEF2ECI(particles(i,:), et0)';
                                end
                            end
                            
                        case "ECEF"
                            x_ECEF = state;
                            [x_ECI, R] = ECEF2ECI(x_ECEF, et0);
                            
                            if app.MonteCarloCheckBox.Value
                                for i = 1:N
                                    particles(i,:) = ECEF2ECI(particles(i,:), et0)';
                                end
                            end
                            
                        case "ECI"
                            x_ECI = state;
                            R = eye(6);
                    end
                    Sigma = updateSigma(Sigma, R);
                    
                case "Spherical" % we expect the frame to be purely ENU
%                   [r,alpha,beta,rdot,alphadot,betadot]                  
                    switch frame
                        case "ENU"
                            
                            [phi_radar, lambda_radar, h_radar] = app.get_radar_coords();
                            
                            x_sph = state;
                            [x_ENU, J1] = sph2ENU(x_sph);
                            [x_ECEF, R1] = ENU2ECEF(x_ENU, phi_radar, lambda_radar, h_radar);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*R1*J1;
                            % convert variances to radians before updating
                            % (pi/180)^2 because the variance is std^2
                            Sigma([2,3,5,6],[2,3,5,6]) = pi^2/180^2*Sigma([2,3,5,6],[2,3,5,6]);
                            if app.MonteCarloCheckBox.Value
                                for i = 1:N
                                    particles(i,:) = sph2ENU(particles(i,:))';
                                    particles(i,:) = ENU2ECEF(particles(i,:), phi_radar, lambda_radar, h_radar)';
                                    particles(i,:) = ECEF2ECI(particles(i,:), et0)';
                                end
                            end
                            
                        case "ECEF"
                            error("unexpected frame for chosen coordinates")
                        case "ECI"
                            error("unexpected frame for chosen coordinates")
                    end
                    Sigma = updateSigma(Sigma, R);
                case "Lat/Lon/Az/El" % we expect the frame to be purely ECEF
%                   [phi,lambda,h,alpha,beta,v]                    
                    switch frame
                        case "ENU"
                            error("unexpected frame for chosen coordinates")
                        case "ECEF"
                            x_latlonazel = state;
                            [x_ECEF, J2] = latlonazel2ECEF(x_latlonazel);
                            [x_ECI, R2] = ECEF2ECI(x_ECEF, et0);
                            R = R2*J2;
                            % convert variances to radians before updating
                            % (pi/180)^2 because the variance is std^2
                            Sigma([1,2,4,5],[1,2,4,5]) = pi^2/180^2*Sigma([1,2,4,5],[1,2,4,5]);
                            if app.MonteCarloCheckBox.Value
                                for i = 1:N
                                    particles(i,:) = latlonazel2ECEF(particles(i,:))';
                                    particles(i,:) = ECEF2ECI(particles(i,:), et0)';
                                end
                            end
                            
                        case "ECI"
                            error("unexpected frame for chosen coordinates")
                    end
                    Sigma = updateSigma(Sigma, R);
            end
                       
%             m = 1e-9; % [kg]
            m = app.mEditField.Value;
%             A = 4.1347e-9; % [m^2]
            A = app.AEditField.Value;

            flag_TB = (app.ThreeBodySwitch.Value == "On");            
            flag_drag = (app.DragSwitch.Value == "On");
            flag_SRP = (app.SRPSwitch.Value == "On");
            flag_J2 = (app.J2Switch.Value == "On");
            flags = [flag_drag; flag_SRP; flag_TB; flag_J2];
            bodies = {'EARTH','MOON','JUPITER_BARYCENTER'};
            tf = app.get_integration_time();
            tol = 1e-12;
            trace = 0;
            

            % Integrations
            dim_flag = ~app.NondimensionalizebeforeintegrationCheckBox.Value;
            if dim_flag % Leave in km and km/s
                PHI0 = eye(6);
                vec0_ECI = [x_ECI; reshape(PHI0,36,1)];
                evfcn_e = @(t,x) ef_rval3d(t,x,1e6); % past Earth's SOI 156.7856
                [tt_ECI, xx_ECI] = ode78e(@(t,vec) dynamics_ECI(t, vec, et0, flags, A, m, {'MOON','SUN'}), 0, tf, vec0_ECI, tol, trace, evfcn_e);
                x_ECI_f = xx_ECI(end,1:6)';
                PHI_ECI = reshape(xx_ECI(end,7:42),6,6);
                Sigma = updateSigma(Sigma,PHI_ECI);
                t_int_ECI = tt_ECI(end); % [sec] amount of time spent integrating around the Earth
            
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        vec0_ECI = [particles(i,:)'; reshape(PHI0,36,1)];
                        [~, xx_ECI_particle] = ode78e(@(t,vec) dynamics_ECI(t, vec, et0, flags, A, m, {'MOON','SUN'}), 0, tf, vec0_ECI, tol, trace, evfcn_e);
                        particles(i,:) = xx_ECI_particle(end,1:6);
                    end
                end
                
                %% convert to SCI
                [x_SCI, R3] = ECI2SCI(x_ECI_f, et0 + t_int_ECI);
                Sigma = updateSigma(Sigma,R3);
                
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        particles(i,:) = ECI2SCI(particles(i,:), et0 + t_int_ECI)';
                    end
                end
                
                %% Integrate in SCI frame past Jupiter's Orbit
                vec0_SCI = [x_SCI; reshape(PHI0,36,1)];
                evfcn_j = @(t,x) ef_rval3d(t,x,1e9); % past Jupiter's Orbit 6.6846\
                [tt_SCI, xx_SCI] = ode78e(@(t,x) dynamics_SCI(t, x, et0 + t_int_ECI, flags, A, m, bodies), 0, tf, vec0_SCI, tol, trace, evfcn_j);
                x_SCI_f = xx_SCI(end,1:6)';
                PHI_SCI = reshape(xx_SCI(end,7:42),6,6);        
                Sigma = updateSigma(Sigma,PHI_SCI);    
                t_int_SCI = tt_SCI(end); %[sec]
            
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        vec0_SCI = [particles(i,:)'; reshape(PHI0,36,1)];
                        [~, xx_SCI_particle] = ode78e(@(t,x) dynamics_SCI(t, x, et0 + t_int_ECI, flags, A, m, bodies), 0, tf, vec0_SCI, tol, trace, evfcn_j);
                        particles(i,:) = xx_SCI_particle(end,1:6);
                    end
                end
                
                app.plot_results(et0, t_int_ECI, nondim(xx_ECI(:,1:6),'ECI'), t_int_SCI, nondim(xx_SCI(:,1:6),'SCI'));
 
                GM_S = cspice_bodvrd( 'SUN', 'GM', 1 );
                
                [a,e,i,Omega,omega,~,~,ME] = cart2oe(x_SCI_f, GM_S);
                
                app.aEditField.Value = a/app.AU;
                app.eEditField.Value = e;
                app.iEditField.Value = i;
                app.oEditField.Value = Omega;
                app.uEditField.Value = omega;
                app.EEditField.Value = ME; % km^3/s^2
                
                [a_std, e_std, i_std, Omega_std, omega_std, ME_std] = oe_err(x_SCI_f, GM_S, Sigma); % answers come out in degrees
                a_std = a_std/app.AU;
                if app.MonteCarloCheckBox.Value
                    as = zeros(N,1);    es = zeros(N,1);    is = zeros(N,1);
                    Omegas = zeros(N,1); omegas = zeros(N,1);   MEs = zeros(N,1);
                    for i = 1:N
                        [as(i),es(i),is(i),Omegas(i),omegas(i),~,~,MEs(i)] = cart2oe(particles(i,:), GM_S);
                        as(i) = as(i)/app.AU;
                    end
                    a_std = std(rmoutliers(as));
                    e_std = std(rmoutliers(es));
                    i_std = std(rmoutliers(is));
                    Omega_std = std(rmoutliers(Omegas));
                    omega_std = std(rmoutliers(omegas));
                    ME_std = std(rmoutliers(MEs));
                end
                
                app.a_stdEditField.Value = a_std;
                app.e_stdEditField.Value = e_std;
                app.i_stdEditField.Value = i_std;
                app.o_stdEditField.Value = Omega_std;
                app.u_stdEditField.Value = omega_std;
                app.E_stdEditField.Value = ME_std;
                
            else % nondimensionalize before performing the integration
            
                %% nondimensionalize
                [x_ECI, A1, DU, VU] = nondim(x_ECI, 'ECI');
                Sigma = updateSigma(Sigma,A1);
                TU = DU/VU;
               
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        particles(i,:) = nondim(particles(i,:), 'ECI')';
                    end
                end

                %% Integrate in ECI past the Earth's Sphere of Influence
    %             PHI0 = diag([1,1,1,0,0,0]); % maybe just the top left block should be an identity? Directly contradicts Montenbruck
                PHI0 = eye(6);
                vec0_ECI = [x_ECI; reshape(PHI0,36,1)];
    
                evfcn_e = @(t,x) ef_rval3d(t,x,150); % past Earth's SOI (about 150 Earth radii) 
                [tt_ECI, xx_ECI] = ode78e(@(t,vec) dynamics_nondim(t, vec, et0, flags, [DU, VU], A, m, {'MOON','SUN'}, "ECI"), 0, tf/TU, vec0_ECI, tol, trace, evfcn_e);
                x_ECI_f = xx_ECI(end,1:6)';
                PHI_ECI = reshape(xx_ECI(end,7:42),6,6);
                Sigma = updateSigma(Sigma,PHI_ECI);
                t_int_ECI = tt_ECI(end)*TU; % [sec] amount of time spent integrating around the Earth
    %             app.MessagesTextArea.Value = sprintf('Earth Integration Time: %f\n',t_int_ECI/86400);
            
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        vec0_ECI = [particles(i,:)'; reshape(PHI0,36,1)];
                        [~, xx_ECI_particle] = ode78e(@(t,vec) dynamics_nondim(t, vec, et0, flags, [DU, VU], A, m, {'MOON','SUN'}, "ECI"), 0, tf/TU, vec0_ECI, tol, trace, evfcn_e);
                        particles(i,:) = xx_ECI_particle(end,1:6);
                    end
                end

                
                %% dimensionalize before converting to SCI
                [x_ECI_f, A2] = dim(x_ECI_f, DU, VU);
                Sigma = updateSigma(Sigma,A2);
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        particles(i,:) = dim(particles(i,:), DU, VU)';
                    end
                end
            
                %% convert to SCI
                [x_SCI, R3] = ECI2SCI(x_ECI_f, et0 + t_int_ECI);
                Sigma = updateSigma(Sigma,R3);
                
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        particles(i,:) = ECI2SCI(particles(i,:), et0 + t_int_ECI)';
                    end
                end
                
                %% nondimensionalize again
                [x_SCI, A3, DU, VU] = nondim(x_SCI, 'SCI');                
                Sigma = updateSigma(Sigma,A3);
                TU = DU/VU;
                
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        particles(i,:) = nondim(particles(i,:), 'SCI')';
                    end
                end
                
                %% Integrate in SCI frame past Jupiter's Orbit
                vec0_SCI = [x_SCI; reshape(PHI0,36,1)];
    %             evfcn_j = @(t,x) ef_rval3d(t,x,1e9); % past Jupiter's Orbit 6.6846\
    %             [tt_SCI, xx_SCI] = ode78e(@(t,x) dynamics_SCI(t, x, et0 + t_int_ECI, flags, A, m, bodies), 0, tf, vec0_SCI, tol, trace, evfcn_j);
                evfcn_j = @(t,x) ef_rval3d(t,x,6); % past Jupiter's Orbit (about 6 AU)
                [tt_SCI, xx_SCI] = ode78e(@(t,x) dynamics_nondim(t, x, (et0 + t_int_ECI), flags, [DU, VU], A, m, bodies, "SCI"), 0, tf/TU, vec0_SCI, tol, trace, evfcn_j);
                x_SCI_f = xx_SCI(end,1:6)';
                PHI_SCI = reshape(xx_SCI(end,7:42),6,6);        
                Sigma = updateSigma(Sigma,PHI_SCI);    
                t_int_SCI = tt_SCI(end)*TU;
                if app.MonteCarloCheckBox.Value
                    for i = 1:N
                        vec0_SCI = [particles(i,:)'; reshape(PHI0,36,1)];
                        [~, xx_SCI_particle] = ode78e(@(t,x) dynamics_nondim(t, x, (et0 + t_int_ECI)/TU, flags, [DU, VU], A, m, bodies, "ECI"), 0, tf/TU, vec0_SCI, tol, trace, evfcn_j);
                        particles(i,:) = xx_SCI_particle(end,1:6);
                    end
                end
                
                app.plot_results(et0, t_int_ECI, xx_ECI(:,1:6), t_int_SCI, xx_SCI(:,1:6));
                
                [a,e,i,Omega,omega,~,~,ME] = cart2oe(x_SCI_f, 1);
                
                app.aEditField.Value = a;
                app.eEditField.Value = e;
                app.iEditField.Value = i;
                app.oEditField.Value = Omega;
                app.uEditField.Value = omega;
                app.EEditField.Value = ME*VU^2;
                
                [a_std, e_std, i_std, Omega_std, omega_std, ME_std] = oe_err(x_SCI_f, 1, Sigma); % answers come out in degrees
                ME_std = ME_std*VU^2;
                if app.MonteCarloCheckBox.Value
                    as = zeros(N,1);    es = zeros(N,1);    is = zeros(N,1);
                    Omegas = zeros(N,1); omegas = zeros(N,1);   MEs = zeros(N,1);
                    for i = 1:N
                        [as(i),es(i),is(i),Omegas(i),omegas(i),~,~,MEs(i)] = cart2oe(particles(i,:), 1);
                        MEs(i) = MEs(i)*VU^2;
                    end
                    a_std = std(rmoutliers(as));
                    e_std = std(rmoutliers(es));
                    i_std = std(rmoutliers(is));
                    Omega_std = std(rmoutliers(Omegas));
                    omega_std = std(rmoutliers(omegas));
                    ME_std = std(rmoutliers(MEs));
                end
                
                app.a_stdEditField.Value = a_std;
                app.e_stdEditField.Value = e_std;
                app.i_stdEditField.Value = i_std;
                app.o_stdEditField.Value = Omega_std;
                app.u_stdEditField.Value = omega_std;
                app.E_stdEditField.Value = ME_std;
            end
            
            app.MessagesTextArea.Value = ["Computation time: " + num2str(toc) + " sec", app.get_total_integration_time(t_int_SCI)];
%             app.MessagesTextArea.Value = [app.MessagesTextArea.Value(:), "Computation time: " + num2str(toc) + " sec"];
        end

        % Value changed function: CoordinatesDropDown
        function CoordinatesDropDownValueChanged(app, event)
            value = app.CoordinatesDropDown.Value;
            switch value
                case "Cartesian"
                    app.in1Label.Text = "x [km]";
                    app.in2Label.Text = "y [km]";
                    app.in3Label.Text = "z [km]";
                    app.in4Label.Text = "dx/dt [km/s]";
                    app.in5Label.Text = "dy/dt [km/s]";
                    app.in6Label.Text = "dz/dt [km/s]";
                case "Spherical"
                    app.in1Label.Text = "r [km]";
                    app.in2Label.Text = "α (az) [deg]";
                    app.in3Label.Text = "β (el) [deg]";
                    app.in4Label.Text = "dr/dt [km]";
                    app.in5Label.Text = "dα/dt [deg/s]";
                    app.in6Label.Text = "dβ/dt [deg/s]";
                    app.FrameDropDown.Value = "ENU";
                case "Lat/Lon/Az/El"
                    app.in1Label.Text = "φ (lat) [deg]";
                    app.in2Label.Text = "λ (lon) [deg]";
                    app.in3Label.Text = "h (alt) [km]";
                    app.in4Label.Text = "α (az) [deg]";
                    app.in5Label.Text = "β (el) [deg]";
                    app.in6Label.Text = "v [km/s]";
                    app.FrameDropDown.Value = "ECEF";
            end
        end

        % Value changed function: PreprogrammedMeteoroidsDropDown
        function PreprogrammedMeteoroidsDropDownValueChanged(app, event)
            verification_data
            value = app.PreprogrammedMeteoroidsDropDown.Value;
            if value == "Custom"
                app.CoordinatesDropDown.Value = "Spherical";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "RISR";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = 101.891;
                app.in2EditField.Value = 26;
                app.in3EditField.Value = 86;
                app.in4EditField.Value = -48.687;
                app.in5EditField.Value = 0;
                app.in6EditField.Value = 0;
                
                app.in1_stdEditField.Value = 0;
                app.in2_stdEditField.Value = 0;
                app.in3_stdEditField.Value = 0;
                app.in4_stdEditField.Value = 0;
                app.in5_stdEditField.Value = 0;
                app.in6_stdEditField.Value = 0;
                
                app.DateDatePicker.Value = datetime(2019,10,18);
                app.hhEditField.Value = 12;
                app.mmEditField.Value = 55;
                app.ssEditField.Value = 23.5165;
                
            elseif value == "ALTAIR-1"
                app.CoordinatesDropDown.Value = "Spherical";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "ALTAIR";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = 113.6788;
                app.in2EditField.Value = 73.5504;
                app.in3EditField.Value = 74.9494;
                app.in4EditField.Value = -66.8296;
                app.in5EditField.Value = -40.1449;
                app.in6EditField.Value = 0.3430;
                
                app.in1_stdEditField.Value = 0.0103;
                app.in2_stdEditField.Value = 0.3136;
                app.in3_stdEditField.Value = 0.1985;
                app.in4_stdEditField.Value = 0.1979;
                app.in5_stdEditField.Value = 1.7367;
                app.in6_stdEditField.Value = 1.0991;
                
                app.DateDatePicker.Value = datetime(2008,1,2);
                app.hhEditField.Value = 18;
                app.mmEditField.Value = 10;
                app.ssEditField.Value = 20.5249;
                
                app.mEditField.Value = 1e-9; % [kg]
                app.AEditField.Value = 4.1347e-9; % [m^2]
                
            elseif value == "ALTAIR-2"
                app.CoordinatesDropDown.Value = "Spherical";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "ALTAIR";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = 113.4188;
                app.in2EditField.Value = 76.1747;
                app.in3EditField.Value = 74.1118;
                app.in4EditField.Value = -59.0507;
                app.in5EditField.Value = 4.1275;
                app.in6EditField.Value = -1.3427;
                
                app.in1_stdEditField.Value = 0.0102;
                app.in2_stdEditField.Value = 0.4210;
                app.in3_stdEditField.Value = 0.2091;
                app.in4_stdEditField.Value = 0.1374;
                app.in5_stdEditField.Value = 1.6012;
                app.in6_stdEditField.Value = 0.6899;
                
                app.DateDatePicker.Value = datetime(2008,1,2);
                app.hhEditField.Value = 18;
                app.mmEditField.Value = 10;
                app.ssEditField.Value = 22.5422;
                
                app.mEditField.Value = 1e-9; % [kg]
                app.AEditField.Value = 4.1347e-9; % [m^2]
                
            elseif value == "Oijarvi"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;
                
                app.in1EditField.Value = oijarvi.lat;
                app.in2EditField.Value = oijarvi.lon;
                app.in3EditField.Value = oijarvi.h;
                app.in4EditField.Value = oijarvi.az;
                app.in5EditField.Value = oijarvi.el;
                app.in6EditField.Value = oijarvi.v;
                
                app.in1_stdEditField.Value = oijarvi.lat_err;
                app.in2_stdEditField.Value = oijarvi.lon_err;
                app.in3_stdEditField.Value = oijarvi.h_err;
                app.in4_stdEditField.Value = oijarvi.az_err;
                app.in5_stdEditField.Value = oijarvi.el_err;
                app.in6_stdEditField.Value = oijarvi.v_err;
                
                app.DateDatePicker.Value = datetime(oijarvi.time(1:3));
                app.hhEditField.Value = oijarvi.time(4);
                app.mmEditField.Value = oijarvi.time(5);
                app.ssEditField.Value = oijarvi.time(6);
                
                app.mEditField.Value = 0;
                app.AEditField.Value = 0;
                
            elseif value == "Mikkeli"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = mikkeli.lat;
                app.in2EditField.Value = mikkeli.lon;
                app.in3EditField.Value = mikkeli.h;
                app.in4EditField.Value = mikkeli.az;
                app.in5EditField.Value = mikkeli.el;
                app.in6EditField.Value = mikkeli.v;
                
                app.in1_stdEditField.Value = mikkeli.lat_err;
                app.in2_stdEditField.Value = mikkeli.lon_err;
                app.in3_stdEditField.Value = mikkeli.h_err;
                app.in4_stdEditField.Value = mikkeli.az_err;
                app.in5_stdEditField.Value = mikkeli.el_err;
                app.in6_stdEditField.Value = mikkeli.v_err;
                
                app.DateDatePicker.Value = datetime(mikkeli.time(1:3));
                app.hhEditField.Value = mikkeli.time(4);
                app.mmEditField.Value = mikkeli.time(5);
                app.ssEditField.Value = mikkeli.time(6);
                
                app.mEditField.Value = 0;
                app.AEditField.Value = 0;
                
            elseif value == "Annama"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = annama.lat;
                app.in2EditField.Value = annama.lon;
                app.in3EditField.Value = annama.h;
                app.in4EditField.Value = annama.az;
                app.in5EditField.Value = annama.el;
                app.in6EditField.Value = annama.v;
                
                app.in1_stdEditField.Value = annama.lat_err;
                app.in2_stdEditField.Value = annama.lon_err;
                app.in3_stdEditField.Value = annama.h_err;
                app.in4_stdEditField.Value = annama.az_err;
                app.in5_stdEditField.Value = annama.el_err;
                app.in6_stdEditField.Value = annama.v_err;
                
                app.DateDatePicker.Value = datetime(annama.time(1:3));
                app.hhEditField.Value = annama.time(4);
                app.mmEditField.Value = annama.time(5);
                app.ssEditField.Value = annama.time(6);
                
                app.mEditField.Value = 0;
                app.AEditField.Value = 0;
                
            elseif value == "Haapavesi"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = haapavesi.lat;
                app.in2EditField.Value = haapavesi.lon;
                app.in3EditField.Value = haapavesi.h;
                app.in4EditField.Value = haapavesi.az;
                app.in5EditField.Value = haapavesi.el;
                app.in6EditField.Value = haapavesi.v;
                
                app.in1_stdEditField.Value = haapavesi.lat_err;
                app.in2_stdEditField.Value = haapavesi.lon_err;
                app.in3_stdEditField.Value = haapavesi.h_err;
                app.in4_stdEditField.Value = haapavesi.az_err;
                app.in5_stdEditField.Value = haapavesi.el_err;
                app.in6_stdEditField.Value = haapavesi.v_err;
                
                app.DateDatePicker.Value = datetime(haapavesi.time(1:3));
                app.hhEditField.Value = haapavesi.time(4);
                app.mmEditField.Value = haapavesi.time(5);
                app.ssEditField.Value = haapavesi.time(6);
                
                app.mEditField.Value = 0;
                app.AEditField.Value = 0;
                
            elseif value == "Kosice"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = kosice.lat;
                app.in2EditField.Value = kosice.lon;
                app.in3EditField.Value = kosice.h;
                app.in4EditField.Value = kosice.az;
                app.in5EditField.Value = kosice.el;
                app.in6EditField.Value = kosice.v;
                
                app.in1_stdEditField.Value = kosice.lat_err;
                app.in2_stdEditField.Value = kosice.lon_err;
                app.in3_stdEditField.Value = kosice.h_err;
                app.in4_stdEditField.Value = kosice.az_err;
                app.in5_stdEditField.Value = kosice.el_err;
                app.in6_stdEditField.Value = kosice.v_err;
                
                app.DateDatePicker.Value = datetime(kosice.time(1:3));
                app.hhEditField.Value = kosice.time(4);
                app.mmEditField.Value = kosice.time(5);
                app.ssEditField.Value = kosice.time(6);
                
                app.mEditField.Value = kosice.m;
                app.AEditField.Value = kosice.A;
                
            elseif value == "Hayabusa"
                app.CoordinatesDropDown.Value = "Lat/Lon/Az/El";
                app.CoordinatesDropDownValueChanged;
                app.RadarDropDown.Value = "N/A";
                app.RadarDropDownValueChanged;

                app.in1EditField.Value = hayabusa1.lat;
                app.in2EditField.Value = hayabusa1.lon;
                app.in3EditField.Value = hayabusa1.h;
                app.in4EditField.Value = hayabusa1.az;
                app.in5EditField.Value = hayabusa1.el;
                app.in6EditField.Value = hayabusa1.v;
                
                app.in1_stdEditField.Value = hayabusa1.lat_err;
                app.in2_stdEditField.Value = hayabusa1.lon_err;
                app.in3_stdEditField.Value = hayabusa1.h_err;
                app.in4_stdEditField.Value = hayabusa1.az_err;
                app.in5_stdEditField.Value = hayabusa1.el_err;
                app.in6_stdEditField.Value = hayabusa1.v_err;
                
                app.DateDatePicker.Value = datetime(hayabusa1.time(1:3));
                app.hhEditField.Value = hayabusa1.time(4);
                app.mmEditField.Value = hayabusa1.time(5);
                app.ssEditField.Value = hayabusa1.time(6);
                
                app.mEditField.Value = hayabusa1.m;
                app.AEditField.Value = hayabusa2.A;
            end
            app.SRPSwitchValueChanged;
            app.DragSwitchValueChanged;
        end

        % Value changed function: DragSwitch
        function DragSwitchValueChanged(app, event)
            if (app.mEditField.Value == 0) || (app.AEditField.Value == 0)
                if app.DragSwitch.Value == "On"
                    app.MessagesTextArea.Value = warning("Drag and SRP do not work without appropriate Mass and Area values. Please enter values for Mass and Area");
                    app.DragSwitch.Value = "Off";
                end
            end   
        end

        % Value changed function: SRPSwitch
        function SRPSwitchValueChanged(app, event)
            if (app.mEditField.Value == 0) || (app.AEditField.Value == 0)
                if app.SRPSwitch.Value == "On"
                    app.MessagesTextArea.Value = warning("Drag and SRP do not work without appropriate mass and area values. Please enter values for Mass and Area");
                    app.SRPSwitch.Value = "Off";
                end
            end   
        end

        % Button pushed function: XYViewButton
        function XYViewButtonPushed(app, event)
            app.UIAxes.View = [0,90];
            axis(app.UIAxes, [-6,6,-6,6,-6,6]);
        end

        % Value changed function: MonteCarloCheckBox
        function MonteCarloCheckBoxValueChanged(app, event)
            value = app.MonteCarloCheckBox.Value;
            if value
                app.NEditField.Editable = true;
            else
                app.NEditField.Editable = false;
            end
        end

        % Value changed function: SignificantDigitsEditField
        function SignificantDigitsEditFieldValueChanged(app, event)
            value = app.SignificantDigitsEditField.Value;
            app.aEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.eEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.iEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.oEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.uEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.EEditField.ValueDisplayFormat = "%."+num2str(value)+"g";

            app.a_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.e_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.i_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.o_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.u_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
            app.E_stdEditField.ValueDisplayFormat = "%."+num2str(value)+"g";
        end

        % Value changed function: RadarDropDown
        function RadarDropDownValueChanged(app, event)
            value = app.RadarDropDown.Value;
            if value == "ALTAIR"
                app.latEditField.Value = 9.40; %[deg] latitude
                app.lonEditField.Value = 167.48; %[deg] longitude
                app.altEditField.Value = 83.5; %[m] altitude
%                 % geodetic location of ALTAIR
%                 phi_radar = rad2deg(0.1640); %[rad converted to deg] latitude
%                 lambda_radar = rad2deg(2.9231); %[rad converted to deg] longitude
%                 h_radar = 0.0835; % [km] altitude
            elseif value == "RISR"
                app.latEditField.Value = 74.72955; %[deg] latitude
                app.lonEditField.Value = -94.90576; %[deg] longitude
                app.altEditField.Value = 145; %[m] altitude
%                 phi_radar = 74.72955; % [deg] latitude
%                 lambda_radar = -94.90576; % [deg] longitude
%                 h_radar = 0.145;    % [km] altitude
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.MODAUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {676, 676, 676};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {676, 676};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x', 253};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MODAUIFigure = uifigure('Visible', 'off');
            app.MODAUIFigure.Name = 'MODA';
            app.MODAUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.MODAUIFigure.KeyPressFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.GridLayout = uigridlayout(app.MODAUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.InitialConditionsPanel = uipanel(app.LeftPanel);
            app.InitialConditionsPanel.Title = 'Initial Conditions';
            app.GridLayout2 = uigridlayout(app.InitialConditionsPanel);
            app.in1Label = uilabel(app.GridLayout2);
            app.in1Label.Text = 'x [km]';
            app.in1EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in1EditField.Value = 30.1051;
            app.in2Label = uilabel(app.GridLayout2);
            app.in2Label.Text = 'y [km]';
            app.in2EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in2EditField.Value = 7.4197;
            app.in3Label = uilabel(app.GridLayout2);
            app.in3Label.Text = 'z [km]';
            app.in3EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in3EditField.Value = 109.086;
            app.in4Label = uilabel(app.GridLayout2);
            app.in4Label.Text = 'dx/dt [km/s]';
            app.in4EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in4EditField.Value = -12.6807;
            app.in5Label = uilabel(app.GridLayout2);
            app.in5Label.Text = 'dy/dt [km/s]';
            app.in5EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in5EditField.Value = -5.4241;
            app.in6Label = uilabel(app.GridLayout2);
            app.in6Label.Text = 'dz/dt [km/s]';
            app.in6EditField = uieditfield(app.GridLayout2, 'numeric');
            app.in6EditField.Value = -57.5224;
            app.StdDevLabel = uilabel(app.GridLayout2);
            app.StdDevLabel.Text = 'Std Dev';
            app.ValLabel = uilabel(app.GridLayout2);
            app.ValLabel.Text = 'Val';
            app.CoordLabel = uilabel(app.GridLayout2);
            app.CoordLabel.Text = 'Coord';
            app.in1_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.in2_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.in3_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.in4_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.in5_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.in6_stdEditField = uieditfield(app.GridLayout2, 'numeric');
            app.CoordinatesDropDownLabel = uilabel(app.LeftPanel);
            app.CoordinatesDropDownLabel.Text = 'Coordinates';
            app.CoordinatesDropDown = uidropdown(app.LeftPanel);
            app.CoordinatesDropDown.Items = {'Cartesian', 'Spherical', 'Lat/Lon/Az/El'};
            app.CoordinatesDropDown.ValueChangedFcn = createCallbackFcn(app, @CoordinatesDropDownValueChanged, true);
            app.CoordinatesDropDown.Value = 'Spherical';
            app.FrameDropDownLabel = uilabel(app.LeftPanel);
            app.FrameDropDownLabel.Text = 'Frame';
            app.FrameDropDown = uidropdown(app.LeftPanel);
            app.FrameDropDown.Items = {'ENU', 'ECEF', 'ECI'};
            app.FrameDropDown.Value = 'ENU';
            app.MasskgEditFieldLabel = uilabel(app.LeftPanel);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]';
            app.mEditField = uieditfield(app.LeftPanel, 'numeric');
            app.mEditField.Value = 1e-09;
            app.AreamLabel = uilabel(app.LeftPanel);
            app.AreamLabel.Text = 'Area [m²]';
            app.AEditField = uieditfield(app.LeftPanel, 'numeric');
            app.AEditField.Value = 4.1347e-09;
            app.PreprogrammedMeteoroidsLabel = uilabel(app.LeftPanel);
            app.PreprogrammedMeteoroidsLabel.Text = {'Pre-programmed '; 'Meteoroids'};
            app.PreprogrammedMeteoroidsDropDown = uidropdown(app.LeftPanel);
            app.PreprogrammedMeteoroidsDropDown.Items = {'Custom', 'ALTAIR-1', 'ALTAIR-2', 'Oijarvi', 'Mikkeli', 'Annama', 'Haapavesi', 'Kosice', 'Hayabusa'};
            app.PreprogrammedMeteoroidsDropDown.ValueChangedFcn = createCallbackFcn(app, @PreprogrammedMeteoroidsDropDownValueChanged, true);
            app.PreprogrammedMeteoroidsDropDown.Value = 'ALTAIR-1';
            app.TimePanel = uipanel(app.LeftPanel);
            app.TimePanel.Title = 'Time';
            app.GridLayout6 = uigridlayout(app.TimePanel);
            app.ssEditFieldLabel = uilabel(app.GridLayout6);
            app.ssEditFieldLabel.Text = 'ss';
            app.ssEditField = uieditfield(app.GridLayout6, 'numeric');
            app.ssEditField.Value = 20.5249;
            app.mmEditFieldLabel = uilabel(app.GridLayout6);
            app.mmEditFieldLabel.Text = 'mm';
            app.mmEditField = uieditfield(app.GridLayout6, 'numeric');
            app.mmEditField.RoundFractionalValues = 'on';
            app.mmEditField.Value = 10;
            app.DateDatePickerLabel = uilabel(app.GridLayout6);
            app.DateDatePickerLabel.Text = 'Date';
            app.DateDatePicker = uidatepicker(app.GridLayout6);
            app.DateDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.DateDatePicker.Value = datetime([2008 1 2]);
            app.IntegrationTimeEditFieldLabel = uilabel(app.GridLayout6);
            app.IntegrationTimeEditFieldLabel.Text = 'Integration Time';
            app.IntegrationTimeEditField = uieditfield(app.GridLayout6, 'numeric');
            app.IntegrationTimeEditField.Value = 1;
            app.hhEditFieldLabel = uilabel(app.GridLayout6);
            app.hhEditFieldLabel.Text = 'hh';
            app.hhEditField = uieditfield(app.GridLayout6, 'numeric');
            app.hhEditField.RoundFractionalValues = 'on';
            app.hhEditField.Value = 18;
            app.timeUnitDropDown = uidropdown(app.GridLayout6);
            app.timeUnitDropDown.Items = {'years', 'days', 'hours', 'minutes', 'seconds'};
            app.timeUnitDropDown.Value = 'years';
            app.RadarPanel = uipanel(app.LeftPanel);
            app.RadarPanel.Title = 'Radar';
            app.GridLayout7 = uigridlayout(app.RadarPanel);
            app.altEditFieldLabel = uilabel(app.GridLayout7);
            app.altEditFieldLabel.Text = 'h (alt) [m] ';
            app.altEditField = uieditfield(app.GridLayout7, 'numeric');
            app.lonEditFieldLabel = uilabel(app.GridLayout7);
            app.lonEditFieldLabel.Text = 'λ (lon) [deg]';
            app.lonEditField = uieditfield(app.GridLayout7, 'numeric');
            app.latEditFieldLabel = uilabel(app.GridLayout7);
            app.latEditFieldLabel.Text = 'φ (lat) [deg]';
            app.latEditField = uieditfield(app.GridLayout7, 'numeric');
            app.RadarDropDownLabel = uilabel(app.GridLayout7);
            app.RadarDropDownLabel.Text = 'Radar';
            app.RadarDropDown = uidropdown(app.GridLayout7);
            app.RadarDropDown.Items = {'ALTAIR', 'RISR', 'N/A'};
            app.RadarDropDown.ValueChangedFcn = createCallbackFcn(app, @RadarDropDownValueChanged, true);
            app.RadarDropDown.Value = 'ALTAIR';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Meteoroid Orbit')
            xlabel(app.UIAxes, 'X [AU]')
            ylabel(app.UIAxes, 'Y [AU]')
            app.PerturbationsPanel = uipanel(app.CenterPanel);
            app.PerturbationsPanel.Title = 'Perturbations';
            app.GridLayout4 = uigridlayout(app.PerturbationsPanel);
            app.ThreeBodyLabel = uilabel(app.GridLayout4);
            app.ThreeBodyLabel.Text = {'Three-'; 'Body'};
            app.ThreeBodySwitch = uiswitch(app.GridLayout4, 'toggle');
            app.DragSwitchLabel = uilabel(app.GridLayout4);
            app.DragSwitchLabel.Text = 'Drag';
            app.DragSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.DragSwitch.ValueChangedFcn = createCallbackFcn(app, @DragSwitchValueChanged, true);
            app.SRPSwitchLabel = uilabel(app.GridLayout4);
            app.SRPSwitchLabel.Text = 'SRP';
            app.SRPSwitch = uiswitch(app.GridLayout4, 'toggle');
            app.SRPSwitch.ValueChangedFcn = createCallbackFcn(app, @SRPSwitchValueChanged, true);
            app.J2SwitchLabel = uilabel(app.GridLayout4);
            app.J2SwitchLabel.Text = 'J2';
            app.J2Switch = uiswitch(app.GridLayout4, 'toggle');
            app.SwitchLabel = uilabel(app.GridLayout4);
            app.SwitchLabel.Text = '??';
            app.Switch = uiswitch(app.GridLayout4, 'toggle');
            app.XYViewButton = uibutton(app.CenterPanel, 'push');
            app.XYViewButton.ButtonPushedFcn = createCallbackFcn(app, @XYViewButtonPushed, true);
            app.XYViewButton.Text = 'XY View';
            app.RightPanel = uipanel(app.GridLayout);
            app.ComputeButton = uibutton(app.RightPanel, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.OrbitalElementsPanel = uipanel(app.RightPanel);
            app.OrbitalElementsPanel.Title = 'Orbital Elements';
            app.GridLayout3 = uigridlayout(app.OrbitalElementsPanel);
            app.aAUEditFieldLabel = uilabel(app.GridLayout3);
            app.aAUEditFieldLabel.Text = 'a [AU]';
            app.aEditField = uieditfield(app.GridLayout3, 'numeric');
            app.aEditField.Editable = 'off';
            app.eEditFieldLabel = uilabel(app.GridLayout3);
            app.eEditFieldLabel.Text = 'e';
            app.eEditField = uieditfield(app.GridLayout3, 'numeric');
            app.eEditField.Editable = 'off';
            app.idegEditFieldLabel = uilabel(app.GridLayout3);
            app.idegEditFieldLabel.Text = 'i [deg]';
            app.iEditField = uieditfield(app.GridLayout3, 'numeric');
            app.iEditField.Editable = 'off';
            app.degEditFieldLabel = uilabel(app.GridLayout3);
            app.degEditFieldLabel.Text = 'Ω [deg]';
            app.oEditField = uieditfield(app.GridLayout3, 'numeric');
            app.oEditField.Editable = 'off';
            app.degEditField_2Label = uilabel(app.GridLayout3);
            app.degEditField_2Label.Text = 'ω [deg]';
            app.uEditField = uieditfield(app.GridLayout3, 'numeric');
            app.uEditField.Editable = 'off';
            app.ValLabel_2 = uilabel(app.GridLayout3);
            app.ValLabel_2.Text = 'Val';
            app.StdDevLabel_2 = uilabel(app.GridLayout3);
            app.StdDevLabel_2.Text = 'Std Dev';
            app.Label = uilabel(app.GridLayout3);
            app.Label.Text = '';
            app.kmsLabel = uilabel(app.GridLayout3);
            app.kmsLabel.Text = 'ε [km²/s²]';
            app.EEditField = uieditfield(app.GridLayout3, 'numeric');
            app.EEditField.Editable = 'off';
            app.a_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.a_stdEditField.Editable = 'off';
            app.e_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.e_stdEditField.Editable = 'off';
            app.i_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.i_stdEditField.Editable = 'off';
            app.o_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.o_stdEditField.Editable = 'off';
            app.u_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.u_stdEditField.Editable = 'off';
            app.E_stdEditField = uieditfield(app.GridLayout3, 'numeric');
            app.E_stdEditField.Editable = 'off';
            app.AdditionalPlottingPanel = uipanel(app.RightPanel);
            app.AdditionalPlottingPanel.Title = 'Additional Plotting';
            app.GridLayout5 = uigridlayout(app.AdditionalPlottingPanel);
            app.EarthOrbitSwitchLabel = uilabel(app.GridLayout5);
            app.EarthOrbitSwitchLabel.Text = 'Earth Orbit';
            app.EarthOrbitSwitch = uiswitch(app.GridLayout5, 'slider');
            app.EarthOrbitSwitch.Value = 'On';
            app.JupiterOrbitSwitchLabel = uilabel(app.GridLayout5);
            app.JupiterOrbitSwitchLabel.Text = 'Jupiter Orbit';
            app.JupiterOrbitSwitch = uiswitch(app.GridLayout5, 'slider');
            app.JupiterOrbitSwitch.Value = 'On';
            app.NEditFieldLabel = uilabel(app.RightPanel);
            app.NEditFieldLabel.Text = 'N';
            app.NEditField = uieditfield(app.RightPanel, 'numeric');
            app.NEditField.Editable = 'off';
            app.NEditField.Value = 100;
            app.MessagesTextAreaLabel = uilabel(app.RightPanel);
            app.MessagesTextAreaLabel.Text = 'Messages';
            app.MessagesTextArea = uitextarea(app.RightPanel);
            app.MonteCarloCheckBox = uicheckbox(app.RightPanel);
            app.MonteCarloCheckBox.ValueChangedFcn = createCallbackFcn(app, @MonteCarloCheckBoxValueChanged, true);
            app.MonteCarloCheckBox.Text = 'Monte Carlo';
            app.NondimensionalizebeforeintegrationCheckBox = uicheckbox(app.RightPanel);
            app.NondimensionalizebeforeintegrationCheckBox.Text = 'Non-dimensionalize before integration';
            app.SignificantDigitsEditFieldLabel = uilabel(app.RightPanel);
            app.SignificantDigitsEditFieldLabel.Text = 'Significant Digits';
            app.SignificantDigitsEditField = uieditfield(app.RightPanel, 'numeric');
            app.SignificantDigitsEditField.Limits = [1 Inf];
            app.SignificantDigitsEditField.RoundFractionalValues = 'on';
            app.SignificantDigitsEditField.ValueDisplayFormat = '%.0f';
            app.SignificantDigitsEditField.ValueChangedFcn = createCallbackFcn(app, @SignificantDigitsEditFieldValueChanged, true);
            app.SignificantDigitsEditField.Value = 4;
            % Show the figure after all components are created
            app.MODAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = moda

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MODAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MODAUIFigure)
        end
    end
end